classdef december < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InteractiveappforspeechsynthesisLabel  matlab.ui.control.Label
        PlotthewaveformsButton  matlab.ui.control.Button
        FastLabel               matlab.ui.control.Label
        slowLabel               matlab.ui.control.Label
        denoisedButton          matlab.ui.control.Button
        PlayButton              matlab.ui.control.Button
        DurationTextArea        matlab.ui.control.TextArea
        DurationTextAreaLabel   matlab.ui.control.Label
        StartrecordingButton    matlab.ui.control.Button
        SpeedValueKnob          matlab.ui.control.Knob
        SpeedValueKnobLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartrecordingButton
        function StartrecordingButtonPushed(app, event)
% Initialize variables
numRecordings = 3;
audioObjects = cell(1, numRecordings);
fileNames = cell(1, numRecordings);

for i = 1:numRecordings
    % Create a unique file name for each recording
    fileName = input(['Enter a name for recording ' num2str(i) ': '], 's');
    fileNames{i} = [fileName '.wav'];

    % Create the audiorecorder object
    audioObjects{i} = audiorecorder;

    % Start recording
    duration = str2double(input('Enter recording duration (in seconds): ', 's'));
    msgbox(['Recording ' num2str(i) ' started']);
    recordblocking(audioObjects{i}, duration);
    msgbox(['Recording ' num2str(i) ' stopped']);

    % Get the audio data
    audioData = getaudiodata(audioObjects{i});

    % Specify the file path for each recording
    filePath = fullfile(pwd, fileNames{i});

    % Write the audio data to a file
    audiowrite(filePath, audioData, audioObjects{i}.SampleRate);

    msgbox(['Audio file ' num2str(i) ' saved as ' fileNames{i}]);
        

end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
% Assuming you have already recorded and saved the audio files

    % Prompt the user to enter the name of the file to play
    fileNameToPlay = input('Enter the name of the audio file to play: ', 's');
    filePathToPlay = fullfile(pwd, [fileNameToPlay '.wav']);

    % Check if the file exists
    if exist(filePathToPlay, 'file') == 2
        % Read the audio file
        [audioDataToPlay, sampleRate] = audioread(filePathToPlay);

        % Plot the waveform
        plotWaveform(audioDataToPlay, sampleRate);

        % Create an audioplayer object with speed adjustment
        player = audioplayer(audioDataToPlay, sampleRate * app.SpeedValue);

        % Play the audio
        play(player);

        % Wait for the playback to finish
        while isplaying(player)
            pause(0.1);
        end
    else
        disp('File does not exist. Please make sure you entered the correct file name.');
    end
function plotWaveform(audioDataToPlay, sampleRate)
    % Plot the waveform of the audio
    time = (0:length(audioDataToPlay)-1) / sampleRate;
    figure;
    plot(time, audioDataToPlay);
    title('Recorded Audio Waveform');
    xlabel('Time (seconds)');
    ylabel('Amplitude');
end

        end

        % Button pushed function: denoisedButton
        function denoisedButtonPushed(app, event)
  % Prompt the user to enter the name of the file to play
fileNameToPlay = input('Enter the name of the audio file to play (without extension): ', 's');
filePathToPlay = fullfile(pwd, [fileNameToPlay '.wav']);

% Check if the file exists
if exist(filePathToPlay, 'file') == 2
    % Read the audio file
    [audioDataToPlay, sampleRate] = audioread(filePathToPlay);

    % Add Gaussian noise with a specified signal-to-noise ratio (SNR)
    snr = 10; % Adjust the SNR as needed
    noisyAudioData = awgn(audioDataToPlay, snr, 'measured');


    % Apply a simple moving average filter for noise reduction
window_size = 100; % Adjust the window size as needed
b = (1/window_size)*ones(1, window_size);
a = 1;
denoised_audio = filter(b, a, noisyAudioData);

% Adjust the gain (make it slightly louder)
gain = 10; % Adjust the gain as needed
louder_denoised_audio = denoised_audio * gain;

% Create an audioplayer object
    player = audioplayer(louder_denoised_audio, sampleRate);

       % Play the audio
    play(player);

    % Wait for the playback to finish
    while isplaying(player)
        pause(0.1);
    end

% Plot the waveform
    plotWaveform(louder_denoised_audio, sampleRate);

else
    disp('File does not exist. Please make sure you entered the correct file name.');
end

function plotWaveform(audioData, sampleRate)
    % Plot the waveform of the audio
    time = (0:length(audioData)-1) / sampleRate;
    figure;
    plot(time, audioData);
    title('denoised Waveform');
    xlabel('Time (seconds)');
    ylabel('Amplitude');
end


        end

        % Button pushed function: PlotthewaveformsButton
        function PlotthewaveformsButtonPushed(app, event)
         

        fileNameToPlay = input('Enter the name of the audio file to play (without extension): ', 's');
fileNameToDisplay = fullfile(pwd, [fileNameToPlay '.wav']);

        % Read the audio file
        [audioData, sampleRate] = audioread(fileNameToDisplay);

        % Create time vector
        t = (0:length(audioData)-1) / sampleRate;

        % Plot time domain waveform
        figure;
        subplot(2, 1, 1);
        plot(t, audioData);
        title('Time Domain');
        xlabel('Time (seconds)');
        ylabel('Amplitude');

        % Plot frequency domain waveform using FFT
        subplot(2, 1, 2);
        audio_fft = fft(audioData);
        f = sampleRate * (-length(audioData)/2:length(audioData)/2-1) / length(audioData);
        plot(f, fftshift(abs(audio_fft)));
        title('Frequency Domain');
        xlabel('Frequency (Hz)');
        ylabel('Amplitude');
        xlim([0 sampleRate/2]); % Display up to the Nyquist frequency

        % Play the audio
        sound(audioData, sampleRate);
        

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpeedValueKnobLabel = uilabel(app.UIFigure);
            app.SpeedValueKnobLabel.Text = 'SpeedValue';
            app.SpeedValueKnob = uiknob(app.UIFigure, 'continuous');
            app.SpeedValueKnob.Limits = [0 2];
            app.SpeedValueKnob.Value = 1;
            app.StartrecordingButton = uibutton(app.UIFigure, 'push');
            app.StartrecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartrecordingButtonPushed, true);
            app.StartrecordingButton.Text = 'Start recording ';
            app.DurationTextAreaLabel = uilabel(app.UIFigure);
            app.DurationTextAreaLabel.Text = 'Duration';
            app.DurationTextArea = uitextarea(app.UIFigure);
            app.DurationTextArea.Value = {'10'};
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.denoisedButton = uibutton(app.UIFigure, 'push');
            app.denoisedButton.ButtonPushedFcn = createCallbackFcn(app, @denoisedButtonPushed, true);
            app.denoisedButton.Text = 'denoised';
            app.slowLabel = uilabel(app.UIFigure);
            app.slowLabel.Text = 'slow';
            app.FastLabel = uilabel(app.UIFigure);
            app.FastLabel.Text = 'Fast';
            app.PlotthewaveformsButton = uibutton(app.UIFigure, 'push');
            app.PlotthewaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotthewaveformsButtonPushed, true);
            app.PlotthewaveformsButton.Text = 'Plot the waveforms ';
            app.InteractiveappforspeechsynthesisLabel = uilabel(app.UIFigure);
            app.InteractiveappforspeechsynthesisLabel.Text = 'Interactive app for speech synthesis ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = december

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end